classdef dockECDAccount < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Document           matlab.ui.container.GridLayout
        NextSelection      matlab.ui.control.Image
        PreviousSelection  matlab.ui.control.Image
        freenote           matlab.ui.control.TextArea
        freenoteLabel      matlab.ui.control.Label
        icmsMonthsPanel    matlab.ui.container.Panel
        icmsMonthsGrid     matlab.ui.container.GridLayout
        icmsMonth12        matlab.ui.control.Spinner
        icmsMonth12Label   matlab.ui.control.Label
        icmsMonth11        matlab.ui.control.Spinner
        icmsMonth11Label   matlab.ui.control.Label
        icmsMonth10        matlab.ui.control.Spinner
        icmsMonth10Label   matlab.ui.control.Label
        icmsMonth9         matlab.ui.control.Spinner
        icmsMonth9Label    matlab.ui.control.Label
        icmsMonth8         matlab.ui.control.Spinner
        icmsMonth8Label    matlab.ui.control.Label
        icmsMonth7         matlab.ui.control.Spinner
        icmsMonth7Label    matlab.ui.control.Label
        icmsMonth6         matlab.ui.control.Spinner
        icmsMonth6Label    matlab.ui.control.Label
        icmsMonth5         matlab.ui.control.Spinner
        icmsMonth5Label    matlab.ui.control.Label
        icmsMonth4         matlab.ui.control.Spinner
        icmsMonth4Label    matlab.ui.control.Label
        icmsMonth3         matlab.ui.control.Spinner
        icmsMonth3Label    matlab.ui.control.Label
        icmsMonth2         matlab.ui.control.Spinner
        icmsMonth2Label    matlab.ui.control.Label
        icmsMonth1         matlab.ui.control.Spinner
        icmsMonth1Label    matlab.ui.control.Label
        icmsType           matlab.ui.control.DropDown
        icmsTypeLabel      matlab.ui.control.Label
        taxType            matlab.ui.control.DropDown
        taxTypeLabel       matlab.ui.control.Label
        accountInfo        matlab.ui.control.Label
        buttonOK           matlab.ui.control.Button
        btnClose           matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Container
        isDocked = true

        mainApp
        projectData
        inputArgs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, context, indexes)
            
            app.mainApp   = mainApp;       
            app.inputArgs = struct('context', context, 'indexes', indexes);
            
        end

        % Callback function: UIFigure, btnClose
        function closeFcn(app, event)
            
            context = app.inputArgs.context;
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', context)

            delete(app)
            
        end

        % Button pushed function: buttonOK
        function buttonOKButtonPushed(app, event)
            
            if isempty(app.Tree.CheckedNodes)
                app.buttonOK.Enable = "off";
                return
            end

            context = app.inputArgs.context;
            indexes = app.inputArgs.indexes;

            tableIdFields = {app.Tree.CheckedNodes.Tag};
            tableIdFields(cellfun(@(x) isempty(x), tableIdFields)) = [];
            tableIdFields = [sort(tableIdFields(startsWith(tableIdFields, 'x'))), sort(tableIdFields(startsWith(tableIdFields, 'm')))];

            ipcMainMatlabCallsHandler(app.mainApp, app, 'exportECD', context, indexes, tableIdFields)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorSPED';
            app.UIFigure.Icon = 'icon_16.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.btnClose = uiimage(app.GridLayout);
            app.btnClose.ImageClickedFcn = createCallbackFcn(app, @closeFcn, true);
            app.btnClose.Tag = 'Close';
            app.btnClose.ImageSource = 'Delete_12SVG.svg';
            app.Document = uigridlayout(app.GridLayout);
            app.buttonOK = uibutton(app.Document, 'push');
            app.buttonOK.ButtonPushedFcn = createCallbackFcn(app, @buttonOKButtonPushed, true);
            app.buttonOK.Tag = 'OK';
            app.buttonOK.Text = 'OK';
            app.accountInfo = uilabel(app.Document);
            app.accountInfo.Interpreter = 'html';
            app.accountInfo.Text = {'<font style="font-size: 12px;">Conta nº <b>1.1.7.0002.02</b></font>'; 'Provisão de Impostos  ↳  CONTRATOS DE ALUGUEL  - IFRS 16  ↳  Despesa do Exercicio Seguinte  ↳  Despesas a Apropriar no Exercicio Seguinte - IFRS15  ↳  Custos de instalação a apropriar - até 1 ano - IFRS15 - PL  ↳  Custos de comissões a apropriar - até 1 ano - IFRS15 - PL'};
            app.taxTypeLabel = uilabel(app.Document);
            app.taxTypeLabel.Text = 'Apurado?';
            app.taxType = uidropdown(app.Document);
            app.taxType.Items = {'-', 'Não', 'Sim', 'Sim - ICMS'};
            app.taxType.Value = 'Sim - ICMS';
            app.icmsTypeLabel = uilabel(app.Document);
            app.icmsTypeLabel.Text = 'Alíquota ICMS:';
            app.icmsType = uidropdown(app.Document);
            app.icmsType.Items = {'Manual - Anual', 'Manual - Mês a mês', 'Tabela histórica de referência do ICMS (por UF)'};
            app.icmsType.Value = 'Tabela histórica de referência do ICMS (por UF)';
            app.icmsMonthsPanel = uipanel(app.Document);
            app.icmsMonthsGrid = uigridlayout(app.icmsMonthsPanel);
            app.icmsMonth1Label = uilabel(app.icmsMonthsGrid);
            app.icmsMonth1Label.Text = 'JAN:';
            app.icmsMonth1 = uispinner(app.icmsMonthsGrid);
            app.icmsMonth1.Step = 0.5;
            app.icmsMonth1.Limits = [0 100];
            app.icmsMonth1.ValueDisplayFormat = '%.1f';
            app.icmsMonth1.Enable = 'off';
            app.icmsMonth2Label = uilabel(app.icmsMonthsGrid);
            app.icmsMonth2Label.Text = 'FEV:';
            app.icmsMonth2 = uispinner(app.icmsMonthsGrid);
            app.icmsMonth2.Step = 0.5;
            app.icmsMonth2.Limits = [0 100];
            app.icmsMonth2.ValueDisplayFormat = '%.1f';
            app.icmsMonth2.Enable = 'off';
            app.icmsMonth3Label = uilabel(app.icmsMonthsGrid);
            app.icmsMonth3Label.Text = 'MAR:';
            app.icmsMonth3 = uispinner(app.icmsMonthsGrid);
            app.icmsMonth3.Step = 0.5;
            app.icmsMonth3.Limits = [0 100];
            app.icmsMonth3.ValueDisplayFormat = '%.1f';
            app.icmsMonth3.Enable = 'off';
            app.icmsMonth4Label = uilabel(app.icmsMonthsGrid);
            app.icmsMonth4Label.Text = 'ABR:';
            app.icmsMonth4 = uispinner(app.icmsMonthsGrid);
            app.icmsMonth4.Step = 0.5;
            app.icmsMonth4.Limits = [0 100];
            app.icmsMonth4.ValueDisplayFormat = '%.1f';
            app.icmsMonth4.Enable = 'off';
            app.icmsMonth5Label = uilabel(app.icmsMonthsGrid);
            app.icmsMonth5Label.Text = 'MAI:';
            app.icmsMonth5 = uispinner(app.icmsMonthsGrid);
            app.icmsMonth5.Step = 0.5;
            app.icmsMonth5.Limits = [0 100];
            app.icmsMonth5.ValueDisplayFormat = '%.1f';
            app.icmsMonth5.Enable = 'off';
            app.icmsMonth6Label = uilabel(app.icmsMonthsGrid);
            app.icmsMonth6Label.Text = 'JUN:';
            app.icmsMonth6 = uispinner(app.icmsMonthsGrid);
            app.icmsMonth6.Step = 0.5;
            app.icmsMonth6.Limits = [0 100];
            app.icmsMonth6.ValueDisplayFormat = '%.1f';
            app.icmsMonth6.Enable = 'off';
            app.icmsMonth7Label = uilabel(app.icmsMonthsGrid);
            app.icmsMonth7Label.Text = 'JUL:';
            app.icmsMonth7 = uispinner(app.icmsMonthsGrid);
            app.icmsMonth7.Step = 0.5;
            app.icmsMonth7.Limits = [0 100];
            app.icmsMonth7.ValueDisplayFormat = '%.1f';
            app.icmsMonth7.Enable = 'off';
            app.icmsMonth8Label = uilabel(app.icmsMonthsGrid);
            app.icmsMonth8Label.Text = 'AGO:';
            app.icmsMonth8 = uispinner(app.icmsMonthsGrid);
            app.icmsMonth8.Step = 0.5;
            app.icmsMonth8.Limits = [0 100];
            app.icmsMonth8.ValueDisplayFormat = '%.1f';
            app.icmsMonth8.Enable = 'off';
            app.icmsMonth9Label = uilabel(app.icmsMonthsGrid);
            app.icmsMonth9Label.Text = 'SET:';
            app.icmsMonth9 = uispinner(app.icmsMonthsGrid);
            app.icmsMonth9.Step = 0.5;
            app.icmsMonth9.Limits = [0 100];
            app.icmsMonth9.ValueDisplayFormat = '%.1f';
            app.icmsMonth9.Enable = 'off';
            app.icmsMonth10Label = uilabel(app.icmsMonthsGrid);
            app.icmsMonth10Label.Text = 'OUT:';
            app.icmsMonth10 = uispinner(app.icmsMonthsGrid);
            app.icmsMonth10.Step = 0.5;
            app.icmsMonth10.Limits = [0 100];
            app.icmsMonth10.ValueDisplayFormat = '%.1f';
            app.icmsMonth10.Enable = 'off';
            app.icmsMonth11Label = uilabel(app.icmsMonthsGrid);
            app.icmsMonth11Label.Text = 'NOV:';
            app.icmsMonth11 = uispinner(app.icmsMonthsGrid);
            app.icmsMonth11.Step = 0.5;
            app.icmsMonth11.Limits = [0 100];
            app.icmsMonth11.ValueDisplayFormat = '%.1f';
            app.icmsMonth11.Enable = 'off';
            app.icmsMonth12Label = uilabel(app.icmsMonthsGrid);
            app.icmsMonth12Label.Text = 'DEZ:';
            app.icmsMonth12 = uispinner(app.icmsMonthsGrid);
            app.icmsMonth12.Step = 0.5;
            app.icmsMonth12.Limits = [0 100];
            app.icmsMonth12.ValueDisplayFormat = '%.1f';
            app.icmsMonth12.Enable = 'off';
            app.freenoteLabel = uilabel(app.Document);
            app.freenoteLabel.Text = 'Observação:';
            app.freenote = uitextarea(app.Document);
            app.PreviousSelection = uiimage(app.Document);
            app.PreviousSelection.Tooltip = {'Navega para o produto anterior'};
            app.PreviousSelection.ImageSource = 'Previous_32.png';
            app.NextSelection = uiimage(app.Document);
            app.NextSelection.Tooltip = {'Navega para o produto posterior'};
            app.NextSelection.ImageSource = 'After_32.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockECDAccount(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end