classdef dockReportInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Document              matlab.ui.container.GridLayout
        eFiscalizaLabel       matlab.ui.control.Label
        eFiscalizaPanel       matlab.ui.container.Panel
        eFiscalizaGrid        matlab.ui.container.GridLayout
        reportIssue           matlab.ui.control.NumericEditField
        reportIssueLabel      matlab.ui.control.Label
        reportUnit            matlab.ui.control.DropDown
        reportUnitLabel       matlab.ui.control.Label
        reportSystem          matlab.ui.control.DropDown
        reportSystemLabel     matlab.ui.control.Label
        reportLabel           matlab.ui.control.Label
        reportPanel           matlab.ui.container.Panel
        reportGrid            matlab.ui.container.GridLayout
        reportVersion         matlab.ui.control.DropDown
        reportVersionLabel    matlab.ui.control.Label
        reportModelName       matlab.ui.control.DropDown
        reportModelNameLabel  matlab.ui.control.Label
        btnOK                 matlab.ui.control.Button
        btnClose              matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Container
        isDocked = true

        mainApp
        callingApp
        jsBackDoor

        projectData
        context
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function initialValues(app, context)
            if ~isdeployed()
                app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS', 'eFiscaliza HM', 'eFiscaliza DS'};
            end
            app.reportSystem.Value     = app.projectData.modules.(context).ui.system;
            app.reportUnit.Value       = app.projectData.modules.(context).ui.unit;
            app.reportIssue.Value      = app.projectData.modules.(context).ui.issue;
            
            set(app.reportModelName, 'Items', app.projectData.modules.(context).ui.templates, ...
                                     'Value', app.projectData.modules.(context).ui.reportModel)
            app.reportVersion.Value    = app.projectData.modules.(context).ui.reportVersion;
        end

        %-----------------------------------------------------------------%
        function CallingMainApp(app, callType, updateFlag, returnFlag, varargin)
            ipcMainMatlabCallsHandler(app.mainApp, app, callType, updateFlag, returnFlag, varargin{:})
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context)
            
            app.mainApp     = mainApp;
            app.callingApp  = callingApp;

            app.projectData = app.mainApp.projectData;
            app.context     = context;

            app.jsBackDoor  = app.callingApp.jsBackDoor;            
            initialValues(app, context)
            
        end

        % Callback function: UIFigure, btnClose
        function closeFcn(app, event)
            
            CallingMainApp(app, 'closeFcn', false, false, app.context)
            delete(app)
            
        end

        % Value changed function: reportIssue, reportModelName, 
        % ...and 3 other components
        function checkValues(app, event)

            switch event.Source
                case app.reportSystem;    fieldName = 'system';
                case app.reportUnit;      fieldName = 'unit';
                case app.reportIssue;     fieldName = 'issue';
                case app.reportModelName; fieldName = 'reportModel';
                case app.reportVersion;   fieldName = 'reportVersion';
            end
            app.projectData.modules.(app.context).ui.(fieldName) = event.Value;

            app.btnOK.Enable = ~isempty(app.reportUnit.Value)                               && ... % unit
                               (app.reportIssue.Value > 0) && ~isinf(app.reportIssue.Value) && ... % issue
                               ~isempty(app.reportModelName.Value);                                % reportModel
            
        end

        % Button pushed function: btnOK
        function btnOKButtonPushed(app, event)
            
            % ...

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorSPED';
            app.UIFigure.Icon = 'icon_16.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.btnClose = uiimage(app.GridLayout);
            app.btnClose.ImageClickedFcn = createCallbackFcn(app, @closeFcn, true);
            app.btnClose.Tag = 'Close';
            app.btnClose.ImageSource = 'Delete_12SVG.svg';
            app.Document = uigridlayout(app.GridLayout);
            app.btnOK = uibutton(app.Document, 'push');
            app.btnOK.ButtonPushedFcn = createCallbackFcn(app, @btnOKButtonPushed, true);
            app.btnOK.Tag = 'OK';
            app.btnOK.Enable = 'off';
            app.btnOK.Text = 'OK';
            app.reportPanel = uipanel(app.Document);
            app.reportGrid = uigridlayout(app.reportPanel);
            app.reportModelNameLabel = uilabel(app.reportGrid);
            app.reportModelNameLabel.Text = 'Modelo (.json):';
            app.reportModelName = uidropdown(app.reportGrid);
            app.reportModelName.Items = {''};
            app.reportModelName.ValueChangedFcn = createCallbackFcn(app, @checkValues, true);
            app.reportModelName.Value = '';
            app.reportVersionLabel = uilabel(app.reportGrid);
            app.reportVersionLabel.Text = 'Versão do relatório:';
            app.reportVersion = uidropdown(app.reportGrid);
            app.reportVersion.Items = {'Preliminar', 'Definitiva'};
            app.reportVersion.ValueChangedFcn = createCallbackFcn(app, @checkValues, true);
            app.reportVersion.Value = 'Preliminar';
            app.reportLabel = uilabel(app.Document);
            app.reportLabel.Text = 'RELATÓRIO';
            app.eFiscalizaPanel = uipanel(app.Document);
            app.eFiscalizaGrid = uigridlayout(app.eFiscalizaPanel);
            app.reportSystemLabel = uilabel(app.eFiscalizaGrid);
            app.reportSystemLabel.Text = 'Ambiente do sistema de gestão à fiscalização:';
            app.reportSystem = uidropdown(app.eFiscalizaGrid);
            app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS'};
            app.reportSystem.ValueChangedFcn = createCallbackFcn(app, @checkValues, true);
            app.reportSystem.Value = 'eFiscaliza';
            app.reportUnitLabel = uilabel(app.eFiscalizaGrid);
            app.reportUnitLabel.Text = 'Unidade responsável pela fiscalização:';
            app.reportUnit = uidropdown(app.eFiscalizaGrid);
            app.reportUnit.Items = {};
            app.reportUnit.ValueChangedFcn = createCallbackFcn(app, @checkValues, true);
            app.reportUnit.Value = {};
            app.reportIssueLabel = uilabel(app.eFiscalizaGrid);
            app.reportIssueLabel.Text = 'Atividade de inspeção (# ID):';
            app.reportIssue = uieditfield(app.eFiscalizaGrid, 'numeric');
            app.reportIssue.Limits = [-1 Inf];
            app.reportIssue.RoundFractionalValues = 'on';
            app.reportIssue.ValueDisplayFormat = '%d';
            app.reportIssue.ValueChangedFcn = createCallbackFcn(app, @checkValues, true);
            app.reportIssue.Value = -1;
            app.eFiscalizaLabel = uilabel(app.Document);
            app.eFiscalizaLabel.Text = 'eFISCALIZA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockReportInfo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end