classdef dockReportLib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Document              matlab.ui.container.GridLayout
        eFiscalizaLabel       matlab.ui.control.Label
        eFiscalizaPanel       matlab.ui.container.Panel
        eFiscalizaGrid        matlab.ui.container.GridLayout
        Image                 matlab.ui.control.Image
        reportIssue           matlab.ui.control.NumericEditField
        reportIssueLabel      matlab.ui.control.Label
        reportUnit            matlab.ui.control.DropDown
        reportUnitLabel       matlab.ui.control.Label
        reportSystem          matlab.ui.control.DropDown
        reportSystemLabel     matlab.ui.control.Label
        reportLabel           matlab.ui.control.Label
        reportPanel           matlab.ui.container.Panel
        reportGrid            matlab.ui.container.GridLayout
        reportVersion         matlab.ui.control.DropDown
        reportVersionLabel    matlab.ui.control.Label
        reportModelName       matlab.ui.control.DropDown
        reportModelNameLabel  matlab.ui.control.Label
        btnOK                 matlab.ui.control.Button
        btnClose              matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        inputArgs
        projectData
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function updatePanel(app, context)
            % Inicialização do projectData:
            if isempty(app.projectData.modules.(context).ui.system) && ~isequal(app.projectData.modules.(context).ui.system, app.mainApp.General.Report.system)
                app.projectData.modules.(context).ui.system = app.mainApp.General.Report.system;
            end
            
            if isempty(app.projectData.modules.(context).ui.unit)   && ~isequal(app.projectData.modules.(context).ui.unit,   app.mainApp.General.Report.unit)
                app.projectData.modules.(context).ui.unit   = app.mainApp.General.Report.unit;
            end

            % Atualiza painel:
            app.reportSystem.Value     = app.projectData.modules.(context).ui.system;

            set(app.reportUnit, 'Items', app.mainApp.General.eFiscaliza.defaultValues.unit, ...
                                'Value', app.projectData.modules.(context).ui.unit)
            app.reportIssue.Value      = app.projectData.modules.(context).ui.issue;
            
            set(app.reportModelName, 'Items', app.projectData.modules.(context).ui.templates, ...
                                     'Value', app.projectData.modules.(context).ui.reportModel)
            app.reportVersion.Value    = app.projectData.modules.(context).ui.reportVersion;

            % Estado do botão:
            app.btnOK.Enable = ~isempty(app.reportUnit.Value)                               && ... % unit
                               (app.reportIssue.Value > 0) && ~isinf(app.reportIssue.Value) && ... % issue
                               ~isempty(app.reportModelName.Value);                                % reportModel

            if app.btnOK.Enable
                focus(app.btnOK)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context, indexes)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)

                app.inputArgs   = struct('context', context, 'indexes', indexes);
                app.projectData = app.mainApp.projectData;    
                updatePanel(app, context)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Callback function: UIFigure, btnClose
        function closeFcn(app, event)
            
            context = app.inputArgs.context;
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', context)

            delete(app)
            
        end

        % Value changed function: reportIssue, reportModelName, 
        % ...and 3 other components
        function checkValues(app, event)

            context = app.inputArgs.context;

            switch event.Source
                case app.reportSystem;    fieldName = 'system';
                case app.reportUnit;      fieldName = 'unit';
                case app.reportIssue;     fieldName = 'issue';
                case app.reportModelName; fieldName = 'reportModel';
                case app.reportVersion;   fieldName = 'reportVersion';
            end
            updateUiInfo(app.projectData, context, fieldName, event.Value)
            updatePanel(app, context)
            
        end

        % Button pushed function: btnOK
        function btnOKButtonPushed(app, event)
            
            context = app.inputArgs.context;
            indexes = app.inputArgs.indexes;
            ipcMainMatlabCallsHandler(app.mainApp, app, 'reportUserConfirmation', context, indexes)

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
            % <VALIDAÇÕES>
            context = app.inputArgs.context;
            
            msg = '';
            if ~report_checkEFiscalizaIssueId(app.mainApp, app.projectData.modules.(context).ui.issue)
                msg = sprintf('O número da inspeção "%.0f" é inválido.', app.projectData.modules.(context).ui.issue);
            elseif isempty(app.projectData.modules.(context).ui.system)
                msg = 'Ambiente do eFiscaliza precisa ser selecionado.';
            end

            if ~isempty(msg)
                ui.Dialog(app.UIFigure, 'warning', msg);
                return
            end
            % </VALIDAÇÕES>

            % <PROCESSO>
            issueDetails = app.projectData.modules.(context).ui.issueDetails;
            if ~isempty(issueDetails) && (issueDetails.issueId == app.reportIssue.Value)
                report_showIssueDetails(app.mainApp, context)
            else
                if isempty(app.mainApp.eFiscalizaObj) || ~isvalid(app.mainApp.eFiscalizaObj)
                    dialogBox    = struct('id', 'login',    'label', 'Usuário: ', 'type', 'text');
                    dialogBox(2) = struct('id', 'password', 'label', 'Senha: ',   'type', 'password');
                    sendEventToHTMLSource(app.callingApp.jsBackDoor, 'customForm', struct('UUID', 'eFiscalizaSignInPage:IssueQuery', 'Fields', dialogBox, 'Context', context))
                else                    
                    report_queryIssueDetails(app.mainApp, [], context)
                end
            end
            % </PROCESSO>

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorSPED';
            app.UIFigure.Icon = 'icon_16.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.btnClose = uiimage(app.GridLayout);
            app.btnClose.ImageClickedFcn = createCallbackFcn(app, @closeFcn, true);
            app.btnClose.Tag = 'Close';
            app.btnClose.ImageSource = 'Delete_12SVG.svg';
            app.Document = uigridlayout(app.GridLayout);
            app.btnOK = uibutton(app.Document, 'push');
            app.btnOK.ButtonPushedFcn = createCallbackFcn(app, @btnOKButtonPushed, true);
            app.btnOK.Tag = 'OK';
            app.btnOK.Enable = 'off';
            app.btnOK.Text = 'OK';
            app.reportPanel = uipanel(app.Document);
            app.reportGrid = uigridlayout(app.reportPanel);
            app.reportModelNameLabel = uilabel(app.reportGrid);
            app.reportModelNameLabel.Text = 'Modelo (.json):';
            app.reportModelName = uidropdown(app.reportGrid);
            app.reportModelName.Items = {''};
            app.reportModelName.ValueChangedFcn = createCallbackFcn(app, @checkValues, true);
            app.reportModelName.Value = '';
            app.reportVersionLabel = uilabel(app.reportGrid);
            app.reportVersionLabel.Text = 'Versão do relatório:';
            app.reportVersion = uidropdown(app.reportGrid);
            app.reportVersion.Items = {'Preliminar', 'Definitiva'};
            app.reportVersion.ValueChangedFcn = createCallbackFcn(app, @checkValues, true);
            app.reportVersion.Value = 'Preliminar';
            app.reportLabel = uilabel(app.Document);
            app.reportLabel.Text = 'RELATÓRIO';
            app.eFiscalizaPanel = uipanel(app.Document);
            app.eFiscalizaGrid = uigridlayout(app.eFiscalizaPanel);
            app.reportSystemLabel = uilabel(app.eFiscalizaGrid);
            app.reportSystemLabel.Text = 'Ambiente do sistema de gestão à fiscalização:';
            app.reportSystem = uidropdown(app.eFiscalizaGrid);
            app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS', 'eFiscaliza HM', 'eFiscaliza DS'};
            app.reportSystem.ValueChangedFcn = createCallbackFcn(app, @checkValues, true);
            app.reportSystem.Value = 'eFiscaliza';
            app.reportUnitLabel = uilabel(app.eFiscalizaGrid);
            app.reportUnitLabel.Text = 'Unidade responsável pela fiscalização:';
            app.reportUnit = uidropdown(app.eFiscalizaGrid);
            app.reportUnit.Items = {};
            app.reportUnit.ValueChangedFcn = createCallbackFcn(app, @checkValues, true);
            app.reportUnit.Value = {};
            app.reportIssueLabel = uilabel(app.eFiscalizaGrid);
            app.reportIssueLabel.Text = 'Atividade de inspeção (# ID):';
            app.reportIssue = uieditfield(app.eFiscalizaGrid, 'numeric');
            app.reportIssue.Limits = [-1 Inf];
            app.reportIssue.RoundFractionalValues = 'on';
            app.reportIssue.ValueDisplayFormat = '%d';
            app.reportIssue.ValueChangedFcn = createCallbackFcn(app, @checkValues, true);
            app.reportIssue.Value = -1;
            app.Image = uiimage(app.eFiscalizaGrid);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Tooltip = {'Detalhes da inspeção'};
            app.Image.ImageSource = 'Zoom_32.png';
            app.eFiscalizaLabel = uilabel(app.Document);
            app.eFiscalizaLabel.Text = 'eFISCALIZA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockReportLib(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end