classdef winConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        DockModule                   matlab.ui.container.GridLayout
        dockModule_Undock            matlab.ui.control.Image
        dockModule_Close             matlab.ui.control.Image
        SubTabGroup                  matlab.ui.container.TabGroup
        SubTab1                      matlab.ui.container.Tab
        SubGrid1                     matlab.ui.container.GridLayout
        openAuxiliarApp2Debug        matlab.ui.control.CheckBox
        openAuxiliarAppAsDocked      matlab.ui.control.CheckBox
        versionInfo                  matlab.ui.control.Label
        tool_versionInfoRefresh      matlab.ui.control.Image
        versionInfoLabel             matlab.ui.control.Label
        SubTab2                      matlab.ui.container.Tab
        SubGrid2                     matlab.ui.container.GridLayout
        configAnalysisPanel2         matlab.ui.container.Panel
        configAnalysisGrid2          matlab.ui.container.GridLayout
        Cofins                       matlab.ui.control.Spinner
        CofinsLabel                  matlab.ui.control.Label
        PIS                          matlab.ui.control.Spinner
        PISLabel                     matlab.ui.control.Label
        configAnalysisPanel2Label    matlab.ui.control.Label
        configAnalysisPanel1         matlab.ui.container.Panel
        configAnalysisGrid1          matlab.ui.container.GridLayout
        CheckStatus                  matlab.ui.control.DropDown
        CheckStatusLabel             matlab.ui.control.Label
        SortMethod                   matlab.ui.control.DropDown
        SortMethodLabel              matlab.ui.control.Label
        InputType                    matlab.ui.control.DropDown
        InputTypeLabel               matlab.ui.control.Label
        configAnalysisRefresh        matlab.ui.control.Image
        configAnalysisPanel1Label    matlab.ui.control.Label
        SubTab3                      matlab.ui.container.Tab
        SubGrid3                     matlab.ui.container.GridLayout
        reportPanel                  matlab.ui.container.Panel
        reportGrid                   matlab.ui.container.GridLayout
        prjFileCompressionMode       matlab.ui.control.DropDown
        prjFileCompressionModeLabel  matlab.ui.control.Label
        reportDocType                matlab.ui.control.DropDown
        reportDocTypeLabel           matlab.ui.control.Label
        reportLabel                  matlab.ui.control.Label
        eFiscalizaPanel              matlab.ui.container.Panel
        eFiscalizaGrid               matlab.ui.container.GridLayout
        reportUnit                   matlab.ui.control.DropDown
        reportUnitLabel              matlab.ui.control.Label
        reportSystem                 matlab.ui.control.DropDown
        reportSystemLabel            matlab.ui.control.Label
        eFiscalizaRefresh            matlab.ui.control.Image
        eFiscalizaLabel              matlab.ui.control.Label
        SubTab4                      matlab.ui.container.Tab
        SubGrid4                     matlab.ui.container.GridLayout
        userPathButton               matlab.ui.control.Image
        userPath                     matlab.ui.control.EditField
        userPathLabel                matlab.ui.control.Label
        DataHubPOSTButton            matlab.ui.control.Image
        DataHubPOST                  matlab.ui.control.EditField
        DATAHUBPOSTLabel             matlab.ui.control.Label
        Toolbar                      matlab.ui.container.GridLayout
        tool_simulationMode          matlab.ui.control.Image
        tool_openDevTools            matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        defaultValues
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)
                        
                    otherwise
                        error('UnexpectedEvent')
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            persistent customizationStatus
            if isempty(customizationStatus)
                customizationStatus = [false, false, false, false];
            end

            switch tabIndex
                case 0 % STARTUP
                    if app.isDocked
                        app.progressDialog = app.mainApp.progressDialog;
                    else
                        sendEventToHTMLSource(app.jsBackDoor, 'startup', app.mainApp.executionMode);
                        app.progressDialog = ui.ProgressDialog(app.jsBackDoor);
                    end
                    customizationStatus = [false, false, false, false];

                otherwise
                    if customizationStatus(tabIndex)
                        return
                    end

                    customizationStatus(tabIndex) = true;
                    switch tabIndex
                        case 1
                            appName = class(app);

                            % Grid botões "dock":
                            if app.isDocked
                                elToModify = {app.DockModule};
                                elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                                if ~isempty(elDataTag)
                                    sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                                        struct('appName', appName, 'dataTag', elDataTag{1}, 'style', struct('transition', 'opacity 2s ease', 'opacity', '0.5')), ...
                                    });
                                end
                            end
                            
                            % Outros elementos:
                            elToModify = {app.versionInfo};
                            elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                            if ~isempty(elDataTag)
                                ui.TextView.startup(app.jsBackDoor, app.versionInfo, appName);
                            end

                        case 2
                            updatePanel_Analysis(app)

                        case 3
                            updatePanel_Report(app)

                        case 4
                            if ~strcmp(app.mainApp.executionMode, 'webApp')
                                set([app.DataHubPOSTButton, app.userPathButton], 'Enable', 1)
                            end
                            updatePanel_Folder(app)
                    end
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            % Lê a versão de "GeneralSettings.json" que vem junto ao
            % projeto (e não a versão armazenada em "ProgramData").
            projectFolder     = appEngine.util.Path(class.Constants.appName, app.mainApp.rootFolder);
            projectFilePath   = fullfile(projectFolder, 'GeneralSettings.json');
            projectGeneral    = jsondecode(fileread(projectFilePath));

            app.defaultValues = struct('File',   projectGeneral.File, ...
                                       'ECD',    projectGeneral.ECD, ...
                                       'Report', projectGeneral.Report);
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.dockModule_Undock.Enable       = 1;
                app.tool_openDevTools.Enable       = 1;                
                app.tool_versionInfoRefresh.Enable = 1;
                app.openAuxiliarAppAsDocked.Enable = 1;
            end

            if ~isdeployed
                app.openAuxiliarApp2Debug.Enable = 1;
            end
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            % Versão:
            appInfo = util.HtmlTextGenerator.AppInfo( ...
                app.mainApp.General, ...
                app.mainApp.rootFolder, ...
                app.mainApp.executionMode, ...
                app.mainApp.renderCount, ...
                "textview" ...
            );
            ui.TextView.update(app.versionInfo, appInfo);

            % Modo de operação:
            app.openAuxiliarAppAsDocked.Value = app.mainApp.General.operationMode.Dock;
            app.openAuxiliarApp2Debug.Value   = app.mainApp.General.operationMode.Debug;
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function updatePanel_Analysis(app)
            % FILE
            app.InputType.Value   = app.mainApp.General.File.input;
            app.SortMethod.Value  = app.mainApp.General.File.sortMethod;
            app.CheckStatus.Value = app.mainApp.General.File.checkStatus;

            % ECD
            app.PIS.Value         = 100 * app.mainApp.General.ECD.taxConfig.PIS;
            app.Cofins.Value      = 100 * app.mainApp.General.ECD.taxConfig.COFINS;

            if checkEdition(app, 'ANALYSIS')
                app.configAnalysisRefresh.Visible = 1;
            else
                app.configAnalysisRefresh.Visible = 0;
            end
        end

        %-----------------------------------------------------------------%
        function updatePanel_Report(app)
            app.reportSystem.Value  = app.mainApp.General.Report.system;
            set(app.reportUnit, 'Items', app.mainApp.General.eFiscaliza.defaultValues.unit, 'Value', app.mainApp.General.Report.unit)
            app.reportDocType.Value = app.mainApp.General.Report.Document;
            
            if ismember(app.mainApp.General.Report.outputCompressionMode, app.prjFileCompressionMode.Items)
                app.prjFileCompressionMode.Value = app.mainApp.General.Report.outputCompressionMode;
            end

            if checkEdition(app, 'REPORT')
                app.eFiscalizaRefresh.Visible = 1;
            else
                app.eFiscalizaRefresh.Visible = 0;
            end
        end

        %-----------------------------------------------------------------%
        function updatePanel_Folder(app)
            DataHub_POST = app.mainApp.General.fileFolder.DataHub_POST;    
            if isfolder(DataHub_POST)
                app.DataHubPOST.Value = DataHub_POST;
            end

            app.userPath.Value = app.mainApp.General.fileFolder.userPath;                
        end

        %-----------------------------------------------------------------%
        function editionFlag = checkEdition(app, tabName)
            editionFlag   = false;
            currentValues = struct('File',   app.mainApp.General.File, ...
                                   'ECD',    app.mainApp.General.ECD,  ...
                                   'Report', app.mainApp.General.Report);

            switch tabName
                case 'ANALYSIS'
                    if ~isequal(rmfield(currentValues, 'Report'), rmfield(app.defaultValues, 'Report'))
                        editionFlag = true;
                    end
                case 'REPORT'
                    if ~isequal(currentValues.Report, app.defaultValues.Report)
                        editionFlag = true;
                    end
            end
        end

        %-----------------------------------------------------------------%
        function saveGeneralSettings(app)
            appEngine.util.generalSettingsSave(class.Constants.appName, app.mainApp.rootFolder, app.mainApp.General_I, app.mainApp.executionMode)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            try
                appEngine.boot(app, app.Role, mainApp)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn')
            delete(app)
            
        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function DockModuleGroup_ButtonPushed(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.dockModule_Undock
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;
                    
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];

                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.dockModule_Close
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Selection change function: SubTabGroup
        function SubTabGroup_TabSelectionChanged(app, event)
            
            [~, tabIndex] = ismember(app.SubTabGroup.SelectedTab, app.SubTabGroup.Children);
            applyJSCustomizations(app, tabIndex)
            
        end

        % Image clicked function: tool_versionInfoRefresh
        function Toolbar_AppEnvRefreshButtonPushed(app, event)
            
            app.progressDialog.Visible = 'visible';

            htmlContent = util.HtmlTextGenerator.checkUpdate(app.mainApp.General, app.mainApp.rootFolder);
            ui.Dialog(app.UIFigure, "info", htmlContent);       

            app.progressDialog.Visible = 'hidden';

        end

        % Image clicked function: tool_simulationMode
        function Toolbar_SimulationModeButtonPushed(app, event)
            
            msgQuestion   = 'Deseja abrir os arquivos de <b>simulação</b>?';
            userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
            
            if strcmp(userSelection, 'Não')
                return
            end

            app.mainApp.General.operationMode.Simulation = true;
            ipcMainMatlabCallsHandler(app.mainApp, app, 'simulationModeChanged')

        end

        % Image clicked function: tool_openDevTools
        function Toolbar_OpenDevToolsClicked(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'openDevTools')

        end

        % Value changed function: openAuxiliarApp2Debug, 
        % ...and 1 other component
        function Config_GeneralParameterValueChanged(app, event)
            
            switch event.Source
                case app.openAuxiliarAppAsDocked
                    app.mainApp.General.operationMode.Dock  = app.openAuxiliarAppAsDocked.Value;

                case app.openAuxiliarApp2Debug
                    app.mainApp.General.operationMode.Debug = app.openAuxiliarApp2Debug.Value;
            end

            app.mainApp.General_I.operationMode = app.mainApp.General.operationMode;
            saveGeneralSettings(app)
            
        end

        % Image clicked function: configAnalysisRefresh
        function Config_AnalysisRefreshImageClicked(app, event)
            
            if ~checkEdition(app, 'ANALYSIS')
                app.configAnalysisRefresh.Visible = 0;
                return

            else
                app.mainApp.General.File   = app.defaultValues.File;
                app.mainApp.General.ECD    = app.defaultValues.ECD;

                if ~isequal(app.mainApp.General.File.sortMethod, app.SortMethod.Value)
                    ipcMainMatlabCallsHandler(app.mainApp, app, 'fileSortMethodChanged')
                end

                app.mainApp.General_I.File = app.mainApp.General.File;
                app.mainApp.General_I.ECD  = app.mainApp.General.ECD;

                updatePanel_Analysis(app)
                saveGeneralSettings(app)
            end

        end

        % Value changed function: CheckStatus, Cofins, InputType, PIS, 
        % ...and 1 other component
        function Config_AnalysisParameterValueChanged(app, event)
            
            switch event.Source
                case app.InputType
                    app.mainApp.General.File.input           = app.InputType.Value;

                case app.SortMethod
                    app.mainApp.General.File.sortMethod      = app.SortMethod.Value;
                    ipcMainMatlabCallsHandler(app.mainApp, app, 'fileSortMethodChanged')

                case app.CheckStatus
                    app.mainApp.General.File.checkStatus     = app.CheckStatus.Value;

                case app.PIS
                    app.mainApp.General.ECD.taxConfig.PIS    = app.PIS.Value / 100;
                    ipcMainMatlabCallsHandler(app.mainApp, app, 'PISValueChanged')

                case app.Cofins
                    app.mainApp.General.ECD.taxConfig.COFINS = app.Cofins.Value / 100;
                    ipcMainMatlabCallsHandler(app.mainApp, app, 'COFINSValueChanged')
            end

            app.mainApp.General_I.File = app.mainApp.General.File;
            app.mainApp.General_I.ECD  = app.mainApp.General.ECD;

            updatePanel_Analysis(app)
            saveGeneralSettings(app)

        end

        % Image clicked function: eFiscalizaRefresh
        function Config_ProjectRefreshImageClicked(app, event)
            
            if ~checkEdition(app, 'REPORT')
                app.eFiscalizaRefresh.Visible = 0;
                return
            
            else
                app.mainApp.General.Report   = app.defaultValues.Report;
                app.mainApp.General_I.Report = app.mainApp.General.Report;
                
                updatePanel_Report(app)
                saveGeneralSettings(app)
            end

        end

        % Value changed function: prjFileCompressionMode, reportDocType, 
        % ...and 2 other components
        function Config_ProjectParameterValueChanged(app, event)
            
            switch event.Source
                case app.reportSystem
                    app.mainApp.General.Report.system   = event.Value;

                case app.reportUnit
                    app.mainApp.General.Report.unit     = event.Value;

                case app.reportDocType
                    app.mainApp.General.Report.Document = event.Value;

                case app.prjFileCompressionMode
                    app.mainApp.General.Report.outputCompressionMode = event.Value;
            end

            app.mainApp.General_I.Report = app.mainApp.General.Report;

            updatePanel_Report(app)
            saveGeneralSettings(app)
            
        end

        % Image clicked function: DataHubPOSTButton, userPathButton
        function Config_FolderButtonPushed(app, event)
            
            try
                relatedFolder = eval(sprintf('app.%s.Value', event.Source.Tag));                    
            catch
                relatedFolder = app.mainApp.General.fileFolder.(event.Source.Tag);
            end
            
            if isfolder(relatedFolder)
                initialFolder = relatedFolder;
            elseif isfile(relatedFolder)
                initialFolder = fileparts(relatedFolder);
            else
                initialFolder = app.userPath.Value;
            end
            
            selectedFolder = uigetdir(initialFolder);
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                figure(app.UIFigure)
            end

            if selectedFolder
                switch event.Source
                    case app.DataHubPOSTButton
                        if strcmp(app.mainApp.General.fileFolder.DataHub_POST, selectedFolder) 
                            return
                        else
                            selectedFolderFiles = dir(selectedFolder);
                            if ~ismember('.monitorsped_post', {selectedFolderFiles.name})
                                ui.Dialog(app.UIFigure, 'error', 'Não se trata da pasta "DataHub - POST", do monitorSPED.');
                                return
                            end

                            app.DataHubPOST.Value = selectedFolder;
                            app.mainApp.General.fileFolder.DataHub_POST = selectedFolder;
    
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'checkDataHubLampStatus')
                        end

                    case app.userPathButton
                        app.userPath.Value = selectedFolder;
                        app.mainApp.General.fileFolder.userPath = selectedFolder;
                end

                app.mainApp.General_I.fileFolder = app.mainApp.General.fileFolder;
                saveGeneralSettings(app)
                updatePanel_Folder(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorSPED';
            app.UIFigure.Icon = 'icon_16.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.tool_openDevTools = uiimage(app.Toolbar);
            app.tool_openDevTools.ImageClickedFcn = createCallbackFcn(app, @Toolbar_OpenDevToolsClicked, true);
            app.tool_openDevTools.Enable = 'off';
            app.tool_openDevTools.Tooltip = {'Abre DevTools'};
            app.tool_openDevTools.ImageSource = 'Debug_18.png';
            app.tool_simulationMode = uiimage(app.Toolbar);
            app.tool_simulationMode.ImageClickedFcn = createCallbackFcn(app, @Toolbar_SimulationModeButtonPushed, true);
            app.tool_simulationMode.Tooltip = {'Leitura arquivos de simulação'};
            app.tool_simulationMode.ImageSource = 'Import_16.png';
            app.SubTabGroup = uitabgroup(app.GridLayout);
            app.SubTabGroup.SelectionChangedFcn = createCallbackFcn(app, @SubTabGroup_TabSelectionChanged, true);
            app.SubTab1 = uitab(app.SubTabGroup);
            app.SubTab1.Title = 'ASPECTOS GERAIS';
            app.SubGrid1 = uigridlayout(app.SubTab1);
            app.versionInfoLabel = uilabel(app.SubGrid1);
            app.versionInfoLabel.Text = 'AMBIENTE:';
            app.tool_versionInfoRefresh = uiimage(app.SubGrid1);
            app.tool_versionInfoRefresh.ImageClickedFcn = createCallbackFcn(app, @Toolbar_AppEnvRefreshButtonPushed, true);
            app.tool_versionInfoRefresh.Enable = 'off';
            app.tool_versionInfoRefresh.Tooltip = {'Verifica atualizações'};
            app.tool_versionInfoRefresh.ImageSource = 'Refresh_18.png';
            app.versionInfo = uilabel(app.SubGrid1);
            app.versionInfo.Interpreter = 'html';
            app.versionInfo.Text = '';
            app.openAuxiliarAppAsDocked = uicheckbox(app.SubGrid1);
            app.openAuxiliarAppAsDocked.ValueChangedFcn = createCallbackFcn(app, @Config_GeneralParameterValueChanged, true);
            app.openAuxiliarAppAsDocked.Enable = 'off';
            app.openAuxiliarAppAsDocked.Text = 'Modo DOCK: módulos auxiliares abertos na janela principal do app';
            app.openAuxiliarApp2Debug = uicheckbox(app.SubGrid1);
            app.openAuxiliarApp2Debug.ValueChangedFcn = createCallbackFcn(app, @Config_GeneralParameterValueChanged, true);
            app.openAuxiliarApp2Debug.Enable = 'off';
            app.openAuxiliarApp2Debug.Text = 'Modo DEBUG';
            app.SubTab2 = uitab(app.SubTabGroup);
            app.SubTab2.Title = 'ANÁLISE';
            app.SubGrid2 = uigridlayout(app.SubTab2);
            app.configAnalysisPanel1Label = uilabel(app.SubGrid2);
            app.configAnalysisPanel1Label.Text = 'PROCESSO DE LEITURA DOS ARQUIVOS E VISUALIZAÇÃO DOS SEUS METADADOS';
            app.configAnalysisRefresh = uiimage(app.SubGrid2);
            app.configAnalysisRefresh.ImageClickedFcn = createCallbackFcn(app, @Config_AnalysisRefreshImageClicked, true);
            app.configAnalysisRefresh.Visible = 'off';
            app.configAnalysisRefresh.Tooltip = {'Retorna às configurações iniciais'};
            app.configAnalysisRefresh.ImageSource = 'Refresh_18.png';
            app.configAnalysisPanel1 = uipanel(app.SubGrid2);
            app.configAnalysisGrid1 = uigridlayout(app.configAnalysisPanel1);
            app.InputTypeLabel = uilabel(app.configAnalysisGrid1);
            app.InputTypeLabel.Text = 'Entrada:';
            app.InputType = uidropdown(app.configAnalysisGrid1);
            app.InputType.Items = {'file', 'folder'};
            app.InputType.ValueChangedFcn = createCallbackFcn(app, @Config_AnalysisParameterValueChanged, true);
            app.InputType.Value = 'file';
            app.SortMethodLabel = uilabel(app.configAnalysisGrid1);
            app.SortMethodLabel.Text = 'Visualização árvore:';
            app.SortMethod = uidropdown(app.configAnalysisGrid1);
            app.SortMethod.Items = {'CNPJ', 'PERÍODO FISCAL', 'RECEITA FEDERAL'};
            app.SortMethod.ValueChangedFcn = createCallbackFcn(app, @Config_AnalysisParameterValueChanged, true);
            app.SortMethod.Value = 'CNPJ';
            app.CheckStatusLabel = uilabel(app.configAnalysisGrid1);
            app.CheckStatusLabel.Text = 'Consulta situação de arquivo na Receita Federal:';
            app.CheckStatus = uidropdown(app.configAnalysisGrid1);
            app.CheckStatus.Items = {'OnlyCache', 'Cache+RealTime', 'RealTime'};
            app.CheckStatus.ValueChangedFcn = createCallbackFcn(app, @Config_AnalysisParameterValueChanged, true);
            app.CheckStatus.Value = 'Cache+RealTime';
            app.configAnalysisPanel2Label = uilabel(app.SubGrid2);
            app.configAnalysisPanel2Label.Text = 'PROCESSO DE ANÁLISE DOS DADOS';
            app.configAnalysisPanel2 = uipanel(app.SubGrid2);
            app.configAnalysisGrid2 = uigridlayout(app.configAnalysisPanel2);
            app.PISLabel = uilabel(app.configAnalysisGrid2);
            app.PISLabel.Text = 'Valor padrão PIS (%):';
            app.PIS = uispinner(app.configAnalysisGrid2);
            app.PIS.Step = 0.1;
            app.PIS.Limits = [0 Inf];
            app.PIS.ValueDisplayFormat = '%.2f';
            app.PIS.ValueChangedFcn = createCallbackFcn(app, @Config_AnalysisParameterValueChanged, true);
            app.PIS.Value = 0.65;
            app.CofinsLabel = uilabel(app.configAnalysisGrid2);
            app.CofinsLabel.Text = 'Valor padrão COFINS (%):';
            app.Cofins = uispinner(app.configAnalysisGrid2);
            app.Cofins.Step = 0.1;
            app.Cofins.Limits = [0 Inf];
            app.Cofins.ValueDisplayFormat = '%.2f';
            app.Cofins.ValueChangedFcn = createCallbackFcn(app, @Config_AnalysisParameterValueChanged, true);
            app.Cofins.Value = 3;
            app.SubTab3 = uitab(app.SubTabGroup);
            app.SubTab3.Title = 'PROJETO';
            app.SubGrid3 = uigridlayout(app.SubTab3);
            app.eFiscalizaLabel = uilabel(app.SubGrid3);
            app.eFiscalizaLabel.Text = 'INICIALIZAÇÃO eFISCALIZA';
            app.eFiscalizaRefresh = uiimage(app.SubGrid3);
            app.eFiscalizaRefresh.ImageClickedFcn = createCallbackFcn(app, @Config_ProjectRefreshImageClicked, true);
            app.eFiscalizaRefresh.Visible = 'off';
            app.eFiscalizaRefresh.Tooltip = {'Retorna às configurações iniciais'};
            app.eFiscalizaRefresh.ImageSource = 'Refresh_18.png';
            app.eFiscalizaPanel = uipanel(app.SubGrid3);
            app.eFiscalizaGrid = uigridlayout(app.eFiscalizaPanel);
            app.reportSystemLabel = uilabel(app.eFiscalizaGrid);
            app.reportSystemLabel.Text = 'Ambiente do sistema de gestão à fiscalização:';
            app.reportSystem = uidropdown(app.eFiscalizaGrid);
            app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS', 'eFiscaliza HM', 'eFiscaliza DS'};
            app.reportSystem.ValueChangedFcn = createCallbackFcn(app, @Config_ProjectParameterValueChanged, true);
            app.reportSystem.Value = 'eFiscaliza';
            app.reportUnitLabel = uilabel(app.eFiscalizaGrid);
            app.reportUnitLabel.Text = 'Unidade responsável pela fiscalização:';
            app.reportUnit = uidropdown(app.eFiscalizaGrid);
            app.reportUnit.Items = {};
            app.reportUnit.ValueChangedFcn = createCallbackFcn(app, @Config_ProjectParameterValueChanged, true);
            app.reportUnit.Value = {};
            app.reportLabel = uilabel(app.SubGrid3);
            app.reportLabel.Text = 'OUTROS ASPECTOS RELACIONADOS AO PROJETO';
            app.reportPanel = uipanel(app.SubGrid3);
            app.reportGrid = uigridlayout(app.reportPanel);
            app.reportDocTypeLabel = uilabel(app.reportGrid);
            app.reportDocTypeLabel.Text = 'Tipo de documento a gerar:';
            app.reportDocType = uidropdown(app.reportGrid);
            app.reportDocType.Items = {'Relatório de Atividades'};
            app.reportDocType.ValueChangedFcn = createCallbackFcn(app, @Config_ProjectParameterValueChanged, true);
            app.reportDocType.Value = 'Relatório de Atividades';
            app.prjFileCompressionModeLabel = uilabel(app.reportGrid);
            app.prjFileCompressionModeLabel.Text = 'Compressão aplicada ao arquivo de saída do projeto?';
            app.prjFileCompressionMode = uidropdown(app.reportGrid);
            app.prjFileCompressionMode.Items = {'Não', 'Sim'};
            app.prjFileCompressionMode.ValueChangedFcn = createCallbackFcn(app, @Config_ProjectParameterValueChanged, true);
            app.prjFileCompressionMode.Value = 'Sim';
            app.SubTab4 = uitab(app.SubTabGroup);
            app.SubTab4.Title = 'MAPEAMENTO DE PASTAS';
            app.SubGrid4 = uigridlayout(app.SubTab4);
            app.DATAHUBPOSTLabel = uilabel(app.SubGrid4);
            app.DATAHUBPOSTLabel.Text = 'DATAHUB - POST:';
            app.DataHubPOST = uieditfield(app.SubGrid4, 'text');
            app.DataHubPOST.Editable = 'off';
            app.DataHubPOSTButton = uiimage(app.SubGrid4);
            app.DataHubPOSTButton.ImageClickedFcn = createCallbackFcn(app, @Config_FolderButtonPushed, true);
            app.DataHubPOSTButton.Tag = 'DataHub_POST';
            app.DataHubPOSTButton.Enable = 'off';
            app.DataHubPOSTButton.ImageSource = 'OpenFile_36x36.png';
            app.userPathLabel = uilabel(app.SubGrid4);
            app.userPathLabel.Text = 'PASTA DO USUÁRIO:';
            app.userPath = uieditfield(app.SubGrid4, 'text');
            app.userPath.Editable = 'off';
            app.userPathButton = uiimage(app.SubGrid4);
            app.userPathButton.ImageClickedFcn = createCallbackFcn(app, @Config_FolderButtonPushed, true);
            app.userPathButton.Tag = 'userPath';
            app.userPathButton.Enable = 'off';
            app.userPathButton.ImageSource = 'OpenFile_36x36.png';
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.dockModule_Close = uiimage(app.DockModule);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Close.Tag = 'DRIVETEST';
            app.dockModule_Close.Tooltip = {'Fecha módulo'};
            app.dockModule_Close.ImageSource = 'Delete_12SVG_white.svg';
            app.dockModule_Undock = uiimage(app.DockModule);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Undock.Tag = 'DRIVETEST';
            app.dockModule_Undock.Enable = 'off';
            app.dockModule_Undock.Tooltip = {'Reabre módulo em outra janela'};
            app.dockModule_Undock.ImageSource = 'Undock_18White.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end